classdef chooseFolders < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        selectButton                matlab.ui.control.Button
        TextArea                    matlab.ui.control.TextArea
        Label                       matlab.ui.control.Label
        selectButton_2              matlab.ui.control.Button
        TextArea_2                  matlab.ui.control.TextArea
        DicomdatadirectoryLabel     matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        OutputdirectoryLabel        matlab.ui.control.Label
        ChooseyourdirectoriesLabel  matlab.ui.control.Label
        helpButton                  matlab.ui.control.Button
        Lamp                        matlab.ui.control.Lamp
        Lamp_2                      matlab.ui.control.Lamp
        OKButton                    matlab.ui.control.Button
        errorLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        outDir = '' % Variable to store output directory
        dicomDir = ''  % Variable to store dicom data directory
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: selectButton
        function selectButtonPushed(app, event)
            app.outDir = uigetdir();
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            app.TextArea.Value = app.outDir;
            app.Lamp.Color = [0 1 0];
        end

        % Button pushed function: selectButton_2
        function selectButton_2Pushed(app, event)
            app.dicomDir = uigetdir();
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            app.TextArea_2.Value = app.dicomDir;
            app.Lamp_2.Color = [0 1 0];         
        end

        % Button pushed function: helpButton
        function helpButtonPushed(app, event)
            web('https://github.com/micGuerr/MiNPAT/blob/main/doc/demos/get_started.md')
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if (app.Lamp.Color == [0 1 0] & app.Lamp.Color == [0 1 0])
                assignin('base','outDir',app.outDir)
                assignin('base','dicomDir',app.dicomDir)
                delete(app.UIFigure)
            else
                app.errorLabel.Text='Error: Please choose both directories.';
                app.errorLabel.FontColor=[1 0 0];
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.selectButton = uibutton(app.UIFigure, 'push');
            app.selectButton.ButtonPushedFcn = createCallbackFcn(app, @selectButtonPushed, true);
            app.selectButton.Text = 'select';
            app.TextArea = uitextarea(app.UIFigure);
            app.OutputdirectoryLabel = uilabel(app.UIFigure);
            app.OutputdirectoryLabel.Text = 'Output directory:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Path to the folder which will contain all the data produced by the analysis.';
            app.selectButton_2 = uibutton(app.UIFigure, 'push');
            app.selectButton_2.ButtonPushedFcn = createCallbackFcn(app, @selectButton_2Pushed, true);
            app.selectButton_2.Text = 'select';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.DicomdatadirectoryLabel = uilabel(app.UIFigure);
            app.DicomdatadirectoryLabel.Text = 'Dicom data directory:';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Path to the folder containing the DICOM data. It should contain one subfolder for each subject.';
            app.ChooseyourdirectoriesLabel = uilabel(app.UIFigure);
            app.ChooseyourdirectoriesLabel.Text = 'Choose your directories';
            app.helpButton = uibutton(app.UIFigure, 'push');
            app.helpButton.ButtonPushedFcn = createCallbackFcn(app, @helpButtonPushed, true);
            app.helpButton.Text = 'help';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.errorLabel = uilabel(app.UIFigure);
            app.errorLabel.Text = {'error'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = chooseFolders

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end